classdef HoloApp_Lito < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveOutputImageButton          matlab.ui.control.Button
        OutputfilenameEditField        matlab.ui.control.EditField
        OutputfilenameEditFieldLabel   matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        Step3Tab                       matlab.ui.container.Tab
        ApplytoSLMButton               matlab.ui.control.Button
        SLMconnectedLamp               matlab.ui.control.Lamp
        SLMconnectedLampLabel          matlab.ui.control.Label
        EasyHoloAppLabel               matlab.ui.control.Label
        Step2Tab                       matlab.ui.container.Tab
        GSroutineButton                matlab.ui.control.Button
        BeamdiametermmEditField        matlab.ui.control.NumericEditField
        BeamdiametermmEditFieldLabel   matlab.ui.control.Label
        iterationsforGSEditField       matlab.ui.control.NumericEditField
        iterationsforGSEditFieldLabel  matlab.ui.control.Label
        GerchbergSaxtonRoutineLabel    matlab.ui.control.Label
        Step1Tab                       matlab.ui.container.Tab
        ImageSelectionListBox          matlab.ui.control.ListBox
        ImageSelectionListBoxLabel     matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        outDir
        tgDir
        filesList
        target
        phase
        n_iter
        SLM_dims
        SLM_pitch
        beam_diameter
        out_filename
        SLM_connected
        num_boards_found
        wait_For_Trigger
        flip_immediate
        timeout_ms
        SDK
        OutputPulseImageFlip
        OutputPulseImageRefresh
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tgDir = '..\img\tgt\';
            app.filesList = dir(app.tgDir);
            app.ImageSelectionListBox.Items = {app.filesList.name}';
            app.n_iter= app.iterationsforGSEditField.Value;
            app.SLM_dims = [1152,1920];
            app.SLM_pitch = 9.2e-6;
            app.beam_diameter = app.BeamdiametermmEditField.Value;
            app.beam_diameter = app.beam_diameter*1e-3;
            app.outDir = '..\img\out\';
            app.out_filename = app.OutputfilenameEditField.Value;
            app.SLM_connected = false;
          
            axis(app.UIAxes, 'tight')
           
            axis(app.UIAxes2, 'tight')
            colormap(app.UIAxes2, 'gray');
            colormap(app.UIAxes, 'gray');

            %% SLM connection attempt
            bit_depth = 12; %bit depth = 8 for small 512, 12 for 1920
            app.num_boards_found = libpointer('uint32Ptr', 0);
            constructed_okay = libpointer('int32Ptr', 0);
            is_nematic_type = 1;
            RAM_write_enable = 1;
            use_GPU = 0;
            max_transients = 10;
            app.wait_For_Trigger = 0; % This feature is user-settable; use 1 for 'on' or 0 for 'off'
            
            app.flip_immediate = 0; % Only supported on the 1024
            app.timeout_ms = 5000;
            app.SDK = struct();
            
            app.OutputPulseImageFlip = 0;
            app.OutputPulseImageRefresh = 0;
            reg_lut = libpointer('string');
            if ~libisloaded('Blink_C_wrapper')
               loadlibrary('../lib/Blink_C_wrapper.dll', '../lib/Blink_C_wrapper.h');
            end
            if ~libisloaded('ImageGen')
                loadlibrary('../lib/ImageGen.dll', '../lib/ImageGen.h');
            end
            disp('Libraries loaded.')
            disp('Trying to connect')
            
            disp('Trying to create SDK.')
            calllib('Blink_C_wrapper', 'Create_SDK', bit_depth, app.num_boards_found, constructed_okay, is_nematic_type, RAM_write_enable, use_GPU, max_transients, reg_lut);
            if constructed_okay ~= 1
                disp('SDK not constructed ok.')
                
                %% Mirar en MPUS PC
                % errMsg = calllib('Blink_C_wrapper', 'Get_last_error_message');
                % disp(errMsg)
            end
            
            disp(['Num boards: ', num2str(app.num_boards_found.val)])
            
            if app.num_boards_found.val > 0
                app.SLM_connected = true;
                disp('Blink SDK was successfully constructed');
                app.SLMconnectedLamp.Color = [112, 209, 109]./255;
            end

        end

        % Value changed function: ImageSelectionListBox
        function ImageSelectionListBoxValueChanged(app, event)
            value = app.ImageSelectionListBox.Value;
            app.target = imread(strcat(app.tgDir,value)); %Get target
            app.target = double(app.target(:,:,1));
            imagesc(app.UIAxes, app.target);
            
        end

        % Button pushed function: GSroutineButton
        function GSroutineButtonPushed(app, event)
            
            slm_beam_rad = round(app.beam_diameter/(2*app.SLM_pitch)); 
            [slmX, slmY] = meshgrid(-round(app.SLM_dims(2)/2):round(app.SLM_dims(2)/2)-1,-round(app.SLM_dims(1)/2):round(app.SLM_dims(1)/2)-1); 
            slmR = sqrt(slmX.^2 + slmY.^2);
            beam_gauss = exp(-2*slmR.^2/slm_beam_rad^2);

            %% Resize image if needed
            dim_img = size(app.target);

            if ~all(dim_img>app.SLM_dims)
                log_arr = dim_img<=app.SLM_dims;
            
                if log_arr(1)
                    padY = app.SLM_dims(1)-dim_img(1);
                else
                    disp('Image will be cut in y axis');
                end
            
                if log_arr(2)
                    padX = app.SLM_dims(2)-dim_img(2);
                else
                    disp('Image will be cut in x axis');
                end
            end
            
            app.target = padarray(app.target,round([padY padX]./2),0,'both');
            app.target = resize(app.target,app.SLM_dims);
            
            app.phase = GS_routine(app.target,beam_gauss,app.n_iter);

            imagesc(app.UIAxes2,app.phase);
        end

        % Value changed function: iterationsforGSEditField
        function iterationsforGSEditFieldValueChanged(app, event)
            app.n_iter = app.iterationsforGSEditField.Value;
            
        end

        % Value changed function: BeamdiametermmEditField
        function BeamdiametermmEditFieldValueChanged(app, event)
            app.beam_diameter = app.BeamdiametermmEditField.Value .* 1e-3;
            
        end

        % Button pushed function: SaveOutputImageButton
        function SaveOutputImageButtonPushed(app, event)
            imwrite(app.phase,[app.outDir,app.out_filename,'.bmp'])
        end

        % Value changed function: OutputfilenameEditField
        function OutputfilenameEditFieldValueChanged(app, event)
            app.out_filename = app.OutputfilenameEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.SLM_connected
                calllib('Blink_C_wrapper', 'Delete_SDK');
                disp('Deleting SDK and closing app')
            else
                disp('Closing app. No SDK present.')
            end
            delete(app)
            
        end

        % Button pushed function: ApplytoSLMButton
        function ApplytoSLMButtonPushed(app, event)
            if app.num_boards_found.val > 0
                fprintf('Found %u SLM controller(s)\n', app.num_boards_found);
                board_number =1;
                disp('Getting SLM information.')
                height = calllib('Blink_C_wrapper', 'Get_image_height', board_number);
                width = calllib('Blink_C_wrapper', 'Get_image_width', board_number);
                depth = calllib('Blink_C_wrapper', 'Get_image_depth', board_number); %bits per pixel
                Bytes = depth/8;
                ImageOne = libpointer('uint8Ptr', zeros(width*height*Bytes,1));
                % WFC = libpointer('uint8Ptr', zeros(width*height*Bytes,1));
                disp('Loading LUT.')
                calllib('Blink_C_wrapper', 'Load_LUT_file', board_number, 'C:\\Program Files\\Meadowlark Optics\\Blink OverDrive Plus\\LUT Files\\slm6254_at1064_PCIe.LUT');
                % disp('Writing blank image.')
                calllib('Blink_C_wrapper', 'Write_image', board_number, ImageOne, width*height*Bytes, app.wait_For_Trigger, app.flip_immediate, app.OutputPulseImageFlip, app.OutputPulseImageRefresh, app.timeout_ms);
                calllib('Blink_C_wrapper', 'ImageWriteComplete', board_number, app.timeout_ms);  
            else
                disp('Not connected to SLM!')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gerchberg-Saxton Routine Output')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.EasyHoloAppLabel = uilabel(app.UIFigure);
            app.EasyHoloAppLabel.Text = 'Easy HoloApp';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step 1';
            app.ImageSelectionListBoxLabel = uilabel(app.Step1Tab);
            app.ImageSelectionListBoxLabel.Text = '1. Image Selection';
            app.ImageSelectionListBox = uilistbox(app.Step1Tab);
            app.ImageSelectionListBox.Items = {};
            app.ImageSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionListBoxValueChanged, true);
            app.ImageSelectionListBox.Value = {};
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.Title = 'Step 2';
            app.GerchbergSaxtonRoutineLabel = uilabel(app.Step2Tab);
            app.GerchbergSaxtonRoutineLabel.Text = '2. Gerchberg-Saxton Routine';
            app.iterationsforGSEditFieldLabel = uilabel(app.Step2Tab);
            app.iterationsforGSEditFieldLabel.Text = '# iterations for GS';
            app.iterationsforGSEditField = uieditfield(app.Step2Tab, 'numeric');
            app.iterationsforGSEditField.ValueChangedFcn = createCallbackFcn(app, @iterationsforGSEditFieldValueChanged, true);
            app.iterationsforGSEditField.Value = 10;
            app.BeamdiametermmEditFieldLabel = uilabel(app.Step2Tab);
            app.BeamdiametermmEditFieldLabel.Text = 'Beam diameter (mm)';
            app.BeamdiametermmEditField = uieditfield(app.Step2Tab, 'numeric');
            app.BeamdiametermmEditField.ValueChangedFcn = createCallbackFcn(app, @BeamdiametermmEditFieldValueChanged, true);
            app.BeamdiametermmEditField.Value = 11.5;
            app.GSroutineButton = uibutton(app.Step2Tab, 'push');
            app.GSroutineButton.ButtonPushedFcn = createCallbackFcn(app, @GSroutineButtonPushed, true);
            app.GSroutineButton.Text = 'GS routine';
            app.Step3Tab = uitab(app.TabGroup);
            app.Step3Tab.Title = 'Step 3';
            app.SLMconnectedLampLabel = uilabel(app.Step3Tab);
            app.SLMconnectedLampLabel.Text = 'SLM connected?';
            app.SLMconnectedLamp = uilamp(app.Step3Tab);
            app.ApplytoSLMButton = uibutton(app.Step3Tab, 'push');
            app.ApplytoSLMButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoSLMButtonPushed, true);
            app.ApplytoSLMButton.Text = 'Apply to SLM';
            app.OutputfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.OutputfilenameEditFieldLabel.Text = 'Output filename';
            app.OutputfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.OutputfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfilenameEditFieldValueChanged, true);
            app.OutputfilenameEditField.Value = 'test_file';
            app.SaveOutputImageButton = uibutton(app.UIFigure, 'push');
            app.SaveOutputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputImageButtonPushed, true);
            app.SaveOutputImageButton.Text = 'Save Output Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HoloApp_Lito

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end